classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout3          matlab.ui.container.GridLayout
        ControlsPanel        matlab.ui.container.Panel
        EditField_Seed       matlab.ui.control.EditField
        ScremblingSeedLabel  matlab.ui.control.Label
        EditField_Message    matlab.ui.control.EditField
        MessageLabel         matlab.ui.control.Label
        Button_Run           matlab.ui.control.Button
        SNRSlider            matlab.ui.control.Slider
        SNRSliderLabel       matlab.ui.control.Label
        CodeRateButtonGroup  matlab.ui.container.ButtonGroup
        MbpsButton36         matlab.ui.control.RadioButton
        MbpsButton6          matlab.ui.control.RadioButton
        ResultsPanel         matlab.ui.container.Panel
        GridLayout           matlab.ui.container.GridLayout
        UIAxes4              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_Run
        function Button_RunPushed(app, event)
            addpath(genpath('./lib'))
            if app.MbpsButton6.Value == true
                trans_rate = 6;
            elseif app.MbpsButton36.Value == true
                trans_rate = 36;
            end
            message = app.EditField_Message.Value;
            % scrambling_seed = app.EditField_Seed.Value;
            scrambling_seed = str2num(app.EditField_Seed.Value); %#ok<*ST2NM>
            snr = app.SNRSlider.Value;
            task = TASK(trans_rate, message,scrambling_seed, snr);
            task = task.run();
            [~, ~] = task.analyze_gui(app.UIAxes, app.UIAxes2, app.UIAxes3, app.UIAxes4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.ResultsPanel = uipanel(app.GridLayout3);
            app.ResultsPanel.Title = 'Results';
            app.GridLayout = uigridlayout(app.ResultsPanel);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.ControlsPanel = uipanel(app.GridLayout3);
            app.ControlsPanel.Title = 'Controls';
            app.CodeRateButtonGroup = uibuttongroup(app.ControlsPanel);
            app.CodeRateButtonGroup.Title = 'Code Rate';
            app.MbpsButton6 = uiradiobutton(app.CodeRateButtonGroup);
            app.MbpsButton6.Text = '6 Mbps';
            app.MbpsButton6.Value = true;
            app.MbpsButton36 = uiradiobutton(app.CodeRateButtonGroup);
            app.MbpsButton36.Text = '36 Mbps';
            app.SNRSliderLabel = uilabel(app.ControlsPanel);
            app.SNRSliderLabel.Text = 'SNR';
            app.SNRSlider = uislider(app.ControlsPanel);
            app.SNRSlider.Value = 40;
            app.Button_Run = uibutton(app.ControlsPanel, 'push');
            app.Button_Run.ButtonPushedFcn = createCallbackFcn(app, @Button_RunPushed, true);
            app.Button_Run.Text = 'Run';
            app.MessageLabel = uilabel(app.ControlsPanel);
            app.MessageLabel.Text = 'Message';
            app.EditField_Message = uieditfield(app.ControlsPanel, 'text');
            app.EditField_Message.Placeholder = 'Across the Great Wall we can reach every corner in the world.';
            app.EditField_Message.Value = 'Across the Great Wall we can reach every corner in the world.';
            app.ScremblingSeedLabel = uilabel(app.ControlsPanel);
            app.ScremblingSeedLabel.Text = 'Seed';
            app.EditField_Seed = uieditfield(app.ControlsPanel, 'text');
            app.EditField_Seed.Placeholder = '[1 0 1 0 1 0 1]';
            app.EditField_Seed.Value = '[1 0 1 0 1 0 1]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end